classdef assignment2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Label_2       matlab.ui.control.Label
        Label         matlab.ui.control.Label
        KLabel        matlab.ui.control.Label
        SliderDelta   matlab.ui.control.Slider
        SliderOmegaN  matlab.ui.control.Slider
        SliderK       matlab.ui.control.Slider
        UIAxes4       matlab.ui.control.UIAxes
        UIAxes3       matlab.ui.control.UIAxes
        UIAxes2       matlab.ui.control.UIAxes
        UIAxes1       matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Update the plots based on the current slider values
        function updatePlots(app, delta, omega_n, K)
            s = tf('s');
            G = K*omega_n^2 / (s^2 + 2*delta*omega_n*s + omega_n^2);

            cla(app.UIAxes1);
            cla(app.UIAxes2);
            cla(app.UIAxes3);            
            
            % Unit Step Response
            step(app.UIAxes1, G);

            % Pole-Zero Diagram
            pzmap(app.UIAxes2, G);

            % Bode Plot
            bode(app.UIAxes3, G);

            % Transfer Function Display
            [num,den] = tfdata(G);
            str = sprintf('G(s) = %s / %s', poly2str(cell2mat(num),'s'), poly2str(cell2mat(den),'s'));
            cla(app.UIAxes4); % Clear previous text
            text(app.UIAxes4, 0.5, 0.5, str, 'HorizontalAlignment', 'center');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize the plots with default values
            updatePlots(app, 0.5, 1, 1)
        end

        % Value changing function: SliderDelta
        function SliderDeltaValueChanging2(app, event)
            delta = event.Value;
            omega_n = app.SliderOmegaN.Value;
            K = app.SliderK.Value;
            updatePlots(app, delta, omega_n, K)            
        end

        % Value changing function: SliderOmegaN
        function SliderOmegaNValueChanging2(app, event)
            delta = app.SliderDelta.Value;
            omega_n = event.Value;
            K = app.SliderK.Value;
            updatePlots(app, delta, omega_n, K)
        end

        % Value changing function: SliderK
        function SliderKValueChanging2(app, event)
            delta = app.SliderDelta.Value;
            omega_n = app.SliderOmegaN.Value;
            K = event.Value;
            updatePlots(app, delta, omega_n, K)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Unit Step Response')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Pole-Zero Diagram')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Bode Plot')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Transfer Function')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.SliderK = uislider(app.UIFigure);
            app.SliderK.Limits = [0 10];
            app.SliderK.ValueChangingFcn = createCallbackFcn(app, @SliderKValueChanging2, true);
            app.SliderOmegaN = uislider(app.UIFigure);
            app.SliderOmegaN.Limits = [0 20];
            app.SliderOmegaN.ValueChangingFcn = createCallbackFcn(app, @SliderOmegaNValueChanging2, true);
            app.SliderDelta = uislider(app.UIFigure);
            app.SliderDelta.Limits = [0 2];
            app.SliderDelta.ValueChangingFcn = createCallbackFcn(app, @SliderDeltaValueChanging2, true);
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = 'K';
            app.Label = uilabel(app.UIFigure);
            app.Label.Interpreter = 'tex';
            app.Label.Text = 'Ω_n';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Interpreter = 'tex';
            app.Label_2.Text = '\delta';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assignment2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end